classdef MontageSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SavetoExcelMenu                 matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ConfigureCablingButton          matlab.ui.control.Button
        SwitchNSPsButton                matlab.ui.control.Button
        EmptyChannelHandlingPanel       matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        RecordEmptyChannelsCheckBox     matlab.ui.control.CheckBox
        SFDropDown                      matlab.ui.control.DropDown
        SFDropDownLabel                 matlab.ui.control.Label
        SRDropDown                      matlab.ui.control.DropDown
        SRDropDownLabel                 matlab.ui.control.Label
        AddEditArrayPanel               matlab.ui.container.Panel
        SetasGNDButton                  matlab.ui.control.Button
        SetasCOMButton                  matlab.ui.control.Button
        TBarDropDown                    matlab.ui.control.DropDown
        TBarDropDownLabel               matlab.ui.control.Label
        EIDEditField                    matlab.ui.control.NumericEditField
        EIDEditFieldLabel               matlab.ui.control.Label
        PinIDEditField                  matlab.ui.control.EditField
        PinIDEditFieldLabel             matlab.ui.control.Label
        SpikeFilterDropDown             matlab.ui.control.DropDown
        SpikeFilterDropDownLabel        matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        SamplingFilterDropDown          matlab.ui.control.DropDown
        SamplingFilterDropDownLabel     matlab.ui.control.Label
        SamplingRateDropDown            matlab.ui.control.DropDown
        SamplingRateDropDownLabel       matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        NumberofContactsEditField       matlab.ui.control.NumericEditField
        NumberofContactsEditFieldLabel  matlab.ui.control.Label
        ArrayIDEditField                matlab.ui.control.EditField
        ArrayIDEditFieldLabel           matlab.ui.control.Label
        ApplytoCentralButton            matlab.ui.control.Button
        PatientIDEditField              matlab.ui.control.EditField
        PatientIDLabel                  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        NSP1Tab                         matlab.ui.container.Tab
        NSP1_Table                      matlab.ui.control.Table
        NSP2Tab                         matlab.ui.container.Tab
        NSP2_Table                      matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        NSPTable = cell(2,1);
        Indices
        ElectrodesPerTBar = 64;
        ElectrodesPerNSP = 256;
        NumberOfTBars = 4;
        activeEmptyChannels = cell(2,1);
        emptyChannelsPerNSP = [1,1];
        TableHeaders = {'TBarPinID','ElectrodeID','ChannelLabel','Notes','SamplingRate','SamplingFilter','SpikeFilter'};
        srCoding = {'None',0;'500Hz',1;'1kHz',2;'2kHz',3;'10kHz',4;'30kHz',5};
        FilterCoding = {'None',0;'750Hz Highpass',1;'250Hz Highpass',2;'100Hz Highpass',3;'50Hz Lowpass',4;'125Hz Lowpass',5;...
            '250Hz Lowpass',6;'500Hz Lowpass',7;'150Hz Lowpass',8;'10Hz-250Hz Bandpass',9;'2.5kHz Lowpass',10;'2kHz Lowpass',11;'250Hz-5kHz Bandpass',12};
        NICPortName = {'NSP1','NSP2'};
        NSPAddress
    end
    
    properties (Access = private)
        CableConfigurationApp
    end
    
    methods (Access = public)
        
        function updateCurrentTable(app)
            inc = app.EIDEditField.Value;
            noc = app.NumberofContactsEditField.Value;
            al = app.ArrayIDEditField.Value;
            if ~isempty(regexp(al,'\d$','once'))
                al = [al '-'];
            end
            if ceil(log10(abs(noc)+1))==3
                contactLabels = [repmat(app.TypeDropDown.Value,[noc,1]) repmat(al,[noc,1]) reshape(sprintf('%03d',1:noc),[3,noc])'];
            else
                contactLabels = [repmat(app.TypeDropDown.Value,[noc,1]) repmat(al,[noc,1]) reshape(sprintf('%02d',1:noc),[2,noc])'];
            end
            contactLabels = cellstr(contactLabels);
            sr = cellstr(repmat(app.SamplingRateDropDown.Value,[noc,1]));
            sf = cellstr(repmat(app.SamplingFilterDropDown.Value,[noc,1]));
            spkf = cellstr(repmat(app.SpikeFilterDropDown.Value,[noc,1]));
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    T=1;
                case 'NSP2'
                    T=2;
            end
            ind = find(app.NSPTable{T}.(app.TableHeaders{2})==inc);
            app.NSPTable{T}.(app.TableHeaders{3})(ind:ind+noc-1) = contactLabels;
            app.NSPTable{T}.(app.TableHeaders{5})(ind:ind+noc-1) = sr;
            app.NSPTable{T}.(app.TableHeaders{6})(ind:ind+noc-1) = sf;
            if strcmp(app.SpikeFilterDropDown.Enable,'on') == 1
                app.NSPTable{T}.(app.TableHeaders{7})(ind:ind+noc-1) = spkf;
            end
            app.EIDEditField.Value = app.NSPTable{T}.(app.TableHeaders{2})(mod(ind+noc,app.ElectrodesPerNSP+1));
            EIDEditFieldValueChanged(app);
        end
        
        
        function tableCellEdit(app,indices,tab)
            firstRow = indices(1,1);
            firstColumn = indices(1,2);
            newData = event.NewData;
            if firstColumn==3
                if ~contains(newData,'empty')
                    if ~isempty(regexp(newData,'[a-zA-Z]$','once'))
                        newData = sprintf('%s%02d',newData,1);
                    elseif ~isempty(regexp(newData,'[a-zA-Z]\d{1}$','once'))
                        tmp = regexp(newData,'\w*(\d{1})$','tokens');
                        tmp = str2double(cell2mat(vertcat(tmp{:})));
                        newData = sprintf('%s%02d',newData(1:end-1),tmp);
                    end
                end
            end
            app.NSPTable{tab}(firstRow,firstColumn) = cellstr(newData);
        end
        
        
        function tableCellSelection(app,indices,tab)
            firstRow = indices(1,1);
            lastRow = indices(end,1);
            firstColumn = indices(1,2);
            app.Indices = indices;
            app.EIDEditField.Value = app.NSPTable{tab}.(app.TableHeaders{2})(firstRow);
            app.PinIDEditField.Value = app.NSPTable{tab}.(app.TableHeaders{1}){firstRow};
            app.TBarDropDown.Value = regexp(app.NSPTable{tab}.(app.TableHeaders{1}){firstRow},'[A-Z]','match');
            if firstColumn==3
                nRows = length(firstRow:lastRow);
                sr = cellstr(repmat(app.NSPTable{tab}.(app.TableHeaders{5})(firstRow),[nRows,1]));
                sf = cellstr(repmat(app.NSPTable{tab}.(app.TableHeaders{6})(firstRow),[nRows,1]));
                spksf = cellstr(repmat(app.NSPTable{tab}.(app.TableHeaders{7})(firstRow),[nRows,1]));
                if ~contains(app.NSPTable{tab}.(app.TableHeaders{3})(firstRow),'empty')
                    label = cell2mat(app.NSPTable{tab}.(app.TableHeaders{3})(firstRow));
                    tmp = regexp(label,'([\w-]+?)(\d{2,3})$','tokens');
                    tmp = vertcat(tmp{:});
                    [arrayID,num] = tmp{:};
                    num = str2double(num);
                    if ceil(log10(abs(nRows)+1))==3
                        contactLabels = [repmat(arrayID,[nRows,1]) reshape(sprintf('%03d',num:num+nRows-1),[3,nRows])'];
                    else
                        contactLabels = [repmat(arrayID,[nRows,1]) reshape(sprintf('%02d',num:num+nRows-1),[2,nRows])'];
                    end
                    contactLabels = cellstr(contactLabels);
                    app.NSPTable{tab}.(app.TableHeaders{3})(firstRow:lastRow) = contactLabels;
                    app.NSPTable{tab}.(app.TableHeaders{5})(firstRow:lastRow) = sr;
                    app.NSPTable{tab}.(app.TableHeaders{6})(firstRow:lastRow) = sf;
                    app.NSPTable{tab}.(app.TableHeaders{7})(firstRow:lastRow) = spksf;
                end
                emptyChannelHandling(app)
                displayUpdatedTable(app)
            end
        end
        
        
        function emptyChannelHandling(app)
            for i = 1:2
                indexNSP = find(~cellfun(@isempty,regexp(app.NSPTable{i}.(app.TableHeaders{3}),'^empty')));
                sr = cell(length(indexNSP),1);
                sf = cell(length(indexNSP),1);
                sr(:) = app.srCoding(1,1);
                sf(:) = app.FilterCoding(1,1);
                spkf = cellstr(blanks(length(indexNSP))');
                app.NSPTable{i}.(app.TableHeaders{5})(indexNSP) = sr;
                app.NSPTable{i}.(app.TableHeaders{6})(indexNSP) = sf;
                app.NSPTable{i}.(app.TableHeaders{7})(indexNSP) = spkf;
            end
            
            if app.RecordEmptyChannelsCheckBox.Value == 1
                app.DropDown.Enable = 1;
                app.SRDropDown.Enable = 1;
                app.SFDropDown.Enable = 1;
                
                switch app.DropDown.Value
                    case 'All'
                        for i = 1:2
                            indexNSP = find(~cellfun(@isempty,regexp(app.NSPTable{i}.(app.TableHeaders{3}),'^empty')));
                            app.activeEmptyChannels{i} = indexNSP;
                        end
                        
                    case 'One'
                        for i = 1:2
                            indexNSP = find(~cellfun(@isempty,regexp(app.NSPTable{i}.(app.TableHeaders{3}),'^empty')),1,'last');
                            app.activeEmptyChannels{i} = indexNSP;
                        end
                        
                    case 'One per TBar'
                        for i = 1:2
                            indAmp = arrayfun(@(x) find(contains(app.NSPTable{i}.(app.TableHeaders{1}),x)),'A':'D','Uni',0);
                            indEmpty = find(~cellfun(@isempty,regexp(app.NSPTable{i}.(app.TableHeaders{3}),'^empty')));
                            tmp = cellfun(@(x) intersect(x,indEmpty),indAmp,'Uni',0);
                            tmp = tmp(~cellfun(@isempty,tmp));
                            indexNSP = cell2mat(cellfun(@(x) x(end),tmp,'Uni',0));
                            app.activeEmptyChannels{i} = indexNSP;
                        end
                end
                
                for i = 1:2
                    if ~isempty(app.activeEmptyChannels{i})
                        sr = cellstr(repmat(app.SRDropDown.Value,[length(app.activeEmptyChannels{i}),1]));
                        sf = cellstr(repmat(app.SFDropDown.Value,[length(app.activeEmptyChannels{i}),1]));
                        app.NSPTable{i}.(app.TableHeaders{5})(app.activeEmptyChannels{i}) = sr;
                        app.NSPTable{i}.(app.TableHeaders{6})(app.activeEmptyChannels{i}) = sf;
                    end
                end
                
            else
                app.DropDown.Enable = 0;
                app.SRDropDown.Enable = 0;
                app.SFDropDown.Enable = 0;
                
                app.activeEmptyChannels{1} = [];
                app.activeEmptyChannels{2} = [];
            end
        end
        
        
        function displayUpdatedTable(app)
            app.NSP1_Table.Data = app.NSPTable{1};
            app.NSP2_Table.Data = app.NSPTable{2};
        end
        
        
        function reorderElectrodeID(app,reorderingMatrix)
            for i = 1:size(reorderingMatrix,2)
                app.NSPTable{i}.(app.TableHeaders{2}) = app.NSPTable{i}.(app.TableHeaders{2})(reorderingMatrix(:,i));
            end
            emptyChannelHandling(app)
            displayUpdatedTable(app)
            EIDEditFieldValueChanged(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,'center')
            app.UIFigure.Visible = 'on';
            
            TBars = {'A','B','C','D'};
            tmp = arrayfun(@(x) sprintf('%02d',x),1:app.ElectrodesPerTBar,'Uni',0);
            TBarPinID = {};
            for i = 1:length(TBars)
                TBarPinID = [TBarPinID cellfun(@(x) [TBars{i} x],tmp,'Uni',0)];
            end
            TBarPinID = TBarPinID';

            app.NumberOfTBars = length(TBars);
            ElectrodeID = (1:app.ElectrodesPerNSP)';
            ChannelLabel = cellstr(repmat('empty',[length(ElectrodeID),1]));
            Notes = cellstr(blanks(length(ElectrodeID))');
            SamplingRate = cellstr(blanks(length(ElectrodeID))');
            SamplingFilter = cellstr(blanks(length(ElectrodeID))');
            SpkFilter = cellstr(blanks(length(ElectrodeID))');
            app.NSPTable{1} = table(TBarPinID,ElectrodeID,ChannelLabel,Notes,SamplingRate,SamplingFilter,SpkFilter,'VariableNames',app.TableHeaders);
            app.NSPTable{2} = table(TBarPinID,ElectrodeID,ChannelLabel,Notes,SamplingRate,SamplingFilter,SpkFilter,'VariableNames',app.TableHeaders);
            
            app.NSP1_Table.ColumnName = app.TableHeaders;
            app.NSP2_Table.ColumnName = app.TableHeaders;
%             s = uistyle('HorizontalAlignment','center');
%             addStyle(app.NSP1_Table,s,'table','')
%             addStyle(app.NSP2_Table,s,'table','')
            [~,app.PatientIDEditField.Value] = getNextLogEntry();
            app.NSPAddress = getIPAddressesFromPortNames(app.NICPortName);
            
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {950, 950};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {204, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: SavetoExcelMenu
        function SavetoExcelButtonPushed(app, event)
            [file,path] = uiputfile([app.PatientIDEditField.Value,'_montage.xlsx']);
            figure(app.UIFigure)
            if file==0
                return
            end
            
            for i = 1:2
                writetable(app.NSPTable{i},fullfile(path,file),'Sheet',i)
            end
        end

        % Menu selected function: LoadMenu
        function LoadButtonPushed(app, event)
            [file,dir]=uigetfile({'*.xlsx','Excel Files(*.xlsx'},'Select File');
            figure(app.UIFigure)
            if file==0
                return
            end
            [~,filename]=fileparts(fullfile(dir,file));
            app.PatientIDEditField.Value = erase(filename,'_montage');
            
            for i = 1:2
                app.NSPTable{i} = readtable(fullfile(dir,file),'Sheet',i);
                for j = 4:7
                    if ~iscell(app.NSPTable{i}.(app.TableHeaders{j}))
                        app.NSPTable{i}.(app.TableHeaders{j}) = cellstr(blanks(size(app.NSPTable{i},1))');
                    end
                end
            end
            
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Button pushed function: ApplytoCentralButton
        function ApplytoCentralButtonPushed(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    table = app.NSPTable{1};
                    tab = 1;
                case 'NSP2'
                    table = app.NSPTable{2};
                    tab = 2;
            end
            
            tmpChannelNames = table.(app.TableHeaders{3});
            dash = cellstr(repmat('-',[256,1]));
            tmpElectrodeIDs = cellstr(num2str(table.(app.TableHeaders{2}),'%03d'));
            ChannelLabels = cellfun(@(x,y,z) [x,y,z],tmpChannelNames,dash,tmpElectrodeIDs,'Uni',0);
            
            ChannelSamplingRate = table.(app.TableHeaders{5});
            ChannelSamplingFilter = table.(app.TableHeaders{6});
            ChannelSpikeFilter = table.(app.TableHeaders{7});
            
            sr = zeros(length(ChannelSamplingRate),1);
            sf = zeros(length(ChannelSamplingFilter),1);
            spksf = nan(length(ChannelSpikeFilter),1);
            for i=1:length(sr)
                sr(i) = app.srCoding{strcmp(app.srCoding(:,1),ChannelSamplingRate{i}),2};
                sf(i) = app.FilterCoding{strcmp(app.FilterCoding(:,1),ChannelSamplingFilter{i}),2};
                if isempty(ChannelSpikeFilter{i})
                    continue
                else
                    spksf(i) = app.FilterCoding{strcmp(app.FilterCoding(:,1),ChannelSpikeFilter{i}),2};
                end
            end
            
            if ismac
                uialert(app.UIFigure,'Button does not work for Mac OS.','Mac OS Incompatibility')
                return
            end
            
            try
                cbmex('open',2,'central-addr',app.NSPAddress{tab},'instance',tab-1)
            catch
                if tab==1
                    uialert(app.UIFigure,'Please Open an Instance of Central and Try Again','Cental Not Open')
                else
                    uialert(app.UIFigure,'To upload the channel data for NSP2, please open the second instance of Central and try again','Second Cental Instance Not Open')
                end
                return
            end
            
            for i = 1:256
                if isnan(spksf(i))
                    cbmex('config',table.(app.TableHeaders{2})(i),'smpgroup',sr(i),'smpfilter',sf(i),'instance',tab-1)
                else
                    cbmex('config',table.(app.TableHeaders{2})(i),'smpgroup',sr(i),'smpfilter',sf(i),'spkfilter',spksf(i),'instance',tab-1)
%                     cbmex('config',table.(app.TableHeaders{2})(i),'smpgroup',sr(i),'smpfilter',sf(i),'spkextract',1,'spkfilter',spksf(i),'instance',tab-1)
                end
            end
            cbmex('chanlabel',table.(app.TableHeaders{2}),ChannelLabels,'instance',tab-1)
            pause(3)

            cbmex('close','instance',tab-1)
        end

        % Button pushed function: SwitchNSPsButton
        function SwitchNSPsButtonPushed(app, event)
            app.NSPTable = app.NSPTable([2,1]);
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Value changed function: RecordEmptyChannelsCheckBox
        function RecordEmptyChannelsCheckBoxValueChanged(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    app.emptyChannelsPerNSP(1) = app.RecordEmptyChannelsCheckBox.Value;
                case 'NSP2'
                    app.emptyChannelsPerNSP(2) = app.RecordEmptyChannelsCheckBox.Value;
            end
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Value changed function: SRDropDown
        function SRDropDownValueChanged(app, event)
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Value changed function: SFDropDown
        function SFDropDownValueChanged(app, event)
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Value changed function: TBarDropDown
        function TBarDropDownValueChanged(app, event)
            ind = find(contains(app.NSPTable{1}.(app.TableHeaders{1}),app.TBarDropDown.Value),1);
            app.PinIDEditField.Value = app.NSPTable{1}.(app.TableHeaders{1}){ind};
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    app.EIDEditField.Value = app.NSPTable{1}.(app.TableHeaders{2})(ind);
                case 'NSP2'
                    app.EIDEditField.Value = app.NSPTable{2}.(app.TableHeaders{2})(ind);
            end
        end

        % Value changed function: EIDEditField
        function EIDEditFieldValueChanged(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    ind = find(app.NSPTable{1}.(app.TableHeaders{2})==app.EIDEditField.Value);
                case 'NSP2'
                    ind = find(app.NSPTable{2}.(app.TableHeaders{2})==app.EIDEditField.Value);
            end
            app.PinIDEditField.Value = app.NSPTable{1}.(app.TableHeaders{1}){ind};
            app.TBarDropDown.Value = regexp(app.NSPTable{1}.(app.TableHeaders{1}){ind},'[A-Z]','match');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           delete(app.CableConfigurationApp)
           delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            updateCurrentTable(app)
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            rows = unique(app.Indices(:,1));
            ChannelLabel = cellstr(repmat('empty',[length(rows),1]));
            Notes = cellstr(blanks(length(rows))');
            
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    T=1;
                case 'NSP2'
                    T=2;
            end
            
            app.NSPTable{T}.(app.TableHeaders{3})(rows) = ChannelLabel;
            app.NSPTable{T}.(app.TableHeaders{4})(rows) = Notes;
            
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Cell edit callback: NSP1_Table
        function NSP1_TableCellEdit(app, event)
            indices = event.Indices;
            tab = 1;
            tableCellEdit(app,indices,tab)
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Cell selection callback: NSP1_Table
        function NSP1_TableCellSelection(app, event)
            indices = event.Indices;
            if indices(1,1)==1 && indices(size(indices,1),1)==256
                if ismember(indices(1,2),[1,2])==1
                    app.NSPTable{1} = sortrows(app.NSPTable{1},app.TableHeaders{indices(1,2)});
                    emptyChannelHandling(app)
                    displayUpdatedTable(app)
                else
                    return
                end
            end
            tab = 1;
            tableCellSelection(app,indices,tab)
        end

        % Cell edit callback: NSP2_Table
        function NSP2_TableCellEdit(app, event)
            indices = event.Indices;
            tab = 2;
            tableCellEdit(app,indices,tab)
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Cell selection callback: NSP2_Table
        function NSP2_TableCellSelection(app, event)
            indices = event.Indices;
            if indices(1,1)==1 && indices(size(indices,1),1)==256
                if ismember(indices(1,2),[1,2])==1
                    app.NSPTable{2} = sortrows(app.NSPTable{2},app.TableHeaders{indices(1,2)});
                    emptyChannelHandling(app)
                    displayUpdatedTable(app)
                else
                    return
                end
            end
            tab = 2;
            tableCellSelection(app,indices,tab)
        end

        % Button pushed function: ConfigureCablingButton
        function ConfigureCablingButtonPushed(app, event)
            app.ConfigureCablingButton.Enable = 'off';
            ElectrodeID = (1:app.ElectrodesPerNSP)';
            for i = 1:2
                app.NSPTable{i}.(app.TableHeaders{2}) = ElectrodeID;
            end
            app.CableConfigurationApp = MontageSetupWizard(app);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            if strcmp(app.TypeDropDown.Value,'m')
                app.SamplingRateDropDown.Value = '30kHz';
                app.SamplingFilterDropDown.Value = 'None';
                app.SpikeFilterDropDown.Value = '250Hz Highpass';
                app.SpikeFilterDropDown.Enable = 1;
            else
                app.SamplingRateDropDown.Value = '2kHz';
                app.SamplingFilterDropDown.Value = '500Hz Lowpass';
                app.SpikeFilterDropDown.Enable = 0;
            end
        end

        % Button pushed function: SetasCOMButton
        function SetasCOMButtonPushed(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    T=1;
                case 'NSP2'
                    T=2;
            end
            
            Labels = app.NSPTable{T}.(app.TableHeaders{3});
            
            %Remove Previous COM Label if exists
            Labels = regexprep(Labels,'^C','');
            
            %Overwrite GND Label if on Selected Label or Append COM Label 
            if isempty(regexp(Labels{app.Indices(1)},'^Z','once'))
                Labels{app.Indices(1)} = ['C' Labels{app.Indices(1)}];
            else
                Labels{app.Indices(1)} = regexprep(Labels{app.Indices(1)},'^Z','C');
            end
            
            app.NSPTable{T}.(app.TableHeaders{3}) = Labels;
            
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Button pushed function: SetasGNDButton
        function SetasGNDButtonPushed(app, event)
            switch app.TabGroup.SelectedTab.Title
                case 'NSP1'
                    T=1;
                case 'NSP2'
                    T=2;
            end
            
            Labels = app.NSPTable{T}.(app.TableHeaders{3});
            
            %Remove Previous GND Label if exists
            Labels = regexprep(Labels,'^Z','');
            
            %Overwrite COM Label if on Selected Label or Append GND Label 
            if isempty(regexp(Labels{app.Indices(1)},'^C','once'))
                Labels{app.Indices(1)} = ['Z' Labels{app.Indices(1)}];
            else
                Labels{app.Indices(1)} = regexprep(Labels{app.Indices(1)},'^C','Z');
            end
            
            app.NSPTable{T}.(app.TableHeaders{3}) = Labels;
                        
            emptyChannelHandling(app)
            displayUpdatedTable(app)
        end

        % Value changing function: ArrayIDEditField
        function ArrayIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if isempty(app.TypeDropDown.Value)
                if length(changingValue)<9
                    app.ArrayIDEditField.FontColor = [0 0 0];
                    app.ArrayIDEditField.BackgroundColor = [1 1 1];
                    app.ArrayIDEditField.Tooltip = '';
                    app.ApplyButton.Enable = 1;
                elseif length(changingValue)<10
                    app.ArrayIDEditField.FontColor = [1.00,0.41,0.16];
                    app.ArrayIDEditField.BackgroundColor = [1 1 1];
                    app.ArrayIDEditField.Tooltip = 'Warning: Array ID may be too long when applied to Blackrock Central';
                    app.ApplyButton.Enable = 1;
                else
                    app.ArrayIDEditField.FontColor = [1 1 1];
                    app.ArrayIDEditField.BackgroundColor = [1 0 0];
                    app.ArrayIDEditField.Tooltip = 'Warning: Array ID is too long for Blackrock Central';
                    app.ApplyButton.Enable = 0;
                end
            else
                if length(changingValue)<8
                    app.ArrayIDEditField.FontColor = [0 0 0];
                    app.ArrayIDEditField.BackgroundColor = [1 1 1];
                    app.ArrayIDEditField.Tooltip = '';
                    app.ApplyButton.Enable = 1;
                elseif length(changingValue)<9
                    app.ArrayIDEditField.FontColor = [1.00,0.41,0.16];
                    app.ArrayIDEditField.BackgroundColor = [1 1 1];
                    app.ArrayIDEditField.Tooltip = 'Warning: Array ID may be too long when applied to Blackrock Central';
                    app.ApplyButton.Enable = 1;
                else
                    app.ArrayIDEditField.FontColor = [1 1 1];
                    app.ArrayIDEditField.BackgroundColor = [1 0 0];
                    app.ArrayIDEditField.Tooltip = 'Warning: Array ID is too long for Blackrock Central';
                    app.ApplyButton.Enable = 0;
                end
            end
        end

        % Value changed function: NumberofContactsEditField
        function NumberofContactsEditFieldValueChanged(app, event)
            ArrayIDEditFieldValueChanging(app,event)
            
        end

        % Button down function: NSP1Tab, NSP2Tab
        function SelectTab(app, event)
            switch event.Source.Title
                case 'NSP1'
                    emptyChannelChoice = app.emptyChannelsPerNSP(1);
                case 'NSP2'
                    emptyChannelChoice = app.emptyChannelsPerNSP(2);
            end
            app.RecordEmptyChannelsCheckBox.Value = emptyChannelChoice;
            emptyChannelHandling(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Montage Setup';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SavetoExcelMenu = uimenu(app.FileMenu);
            app.SavetoExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetoExcelButtonPushed, true);
            app.SavetoExcelMenu.Text = 'Save to Excel';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadMenu.Text = 'Load';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PatientIDLabel = uilabel(app.LeftPanel);
            app.PatientIDLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.LeftPanel, 'text');
            app.PatientIDEditField.Editable = 'off';
            app.ApplytoCentralButton = uibutton(app.LeftPanel, 'push');
            app.ApplytoCentralButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoCentralButtonPushed, true);
            app.ApplytoCentralButton.Text = 'Apply to Central';
            app.AddEditArrayPanel = uipanel(app.LeftPanel);
            app.AddEditArrayPanel.Title = 'Add/Edit Array';
            app.ArrayIDEditFieldLabel = uilabel(app.AddEditArrayPanel);
            app.ArrayIDEditFieldLabel.Text = 'Array ID';
            app.ArrayIDEditField = uieditfield(app.AddEditArrayPanel, 'text');
            app.ArrayIDEditField.ValueChangingFcn = createCallbackFcn(app, @ArrayIDEditFieldValueChanging, true);
            app.NumberofContactsEditFieldLabel = uilabel(app.AddEditArrayPanel);
            app.NumberofContactsEditFieldLabel.Text = 'Number of Contacts';
            app.NumberofContactsEditField = uieditfield(app.AddEditArrayPanel, 'numeric');
            app.NumberofContactsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofContactsEditFieldValueChanged, true);
            app.NumberofContactsEditField.Value = 1;
            app.ApplyButton = uibutton(app.AddEditArrayPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SamplingRateDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.SamplingRateDropDownLabel.Text = 'Sampling Rate';
            app.SamplingRateDropDown = uidropdown(app.AddEditArrayPanel);
            app.SamplingRateDropDown.Items = {'None', '500Hz', '1kHz', '2kHz', '10kHz', '30kHz', ''};
            app.SamplingRateDropDown.Value = '2kHz';
            app.SamplingFilterDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.SamplingFilterDropDownLabel.Text = 'Sampling Filter';
            app.SamplingFilterDropDown = uidropdown(app.AddEditArrayPanel);
            app.SamplingFilterDropDown.Items = {'None', '750Hz Highpass', '250Hz Highpass', '100Hz Highpass', '50Hz Lowpass', '125Hz Lowpass', '250Hz Lowpass', '500Hz Lowpass', '150Hz Lowpass', '10Hz-250Hz Bandpass', '2.5kHz Lowpass', '2kHz Lowpass', '250Hz-5kHz Bandpass'};
            app.SamplingFilterDropDown.Value = '500Hz Lowpass';
            app.DefaultButton = uibutton(app.AddEditArrayPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.TypeDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.AddEditArrayPanel);
            app.TypeDropDown.Items = {'sEEG', 'Micro', 'Subdural', 'DBS', 'External'};
            app.TypeDropDown.ItemsData = {'', 'm', 'g', 'd', 'x'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = '';
            app.SpikeFilterDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.SpikeFilterDropDownLabel.Text = 'Spike Filter';
            app.SpikeFilterDropDown = uidropdown(app.AddEditArrayPanel);
            app.SpikeFilterDropDown.Items = {'None', '750Hz Highpass', '250Hz Highpass', '100Hz Highpass', '50Hz Lowpass', '125Hz Lowpass', '250Hz Lowpass', '500Hz Lowpass', '150Hz Lowpass', '10Hz-250Hz Bandpass', '2.5kHz Lowpass', '2kHz Lowpass', '250Hz-5kHz Bandpass'};
            app.SpikeFilterDropDown.Enable = 'off';
            app.SpikeFilterDropDown.Value = '250Hz Highpass';
            app.PinIDEditFieldLabel = uilabel(app.AddEditArrayPanel);
            app.PinIDEditFieldLabel.Text = 'Pin ID';
            app.PinIDEditField = uieditfield(app.AddEditArrayPanel, 'text');
            app.PinIDEditField.Editable = 'off';
            app.PinIDEditField.Value = 'A01';
            app.EIDEditFieldLabel = uilabel(app.AddEditArrayPanel);
            app.EIDEditFieldLabel.Text = 'EID';
            app.EIDEditField = uieditfield(app.AddEditArrayPanel, 'numeric');
            app.EIDEditField.Limits = [1 256];
            app.EIDEditField.ValueChangedFcn = createCallbackFcn(app, @EIDEditFieldValueChanged, true);
            app.EIDEditField.Value = 1;
            app.TBarDropDownLabel = uilabel(app.AddEditArrayPanel);
            app.TBarDropDownLabel.Text = 'T-Bar';
            app.TBarDropDown = uidropdown(app.AddEditArrayPanel);
            app.TBarDropDown.Items = {'A', 'B', 'C', 'D'};
            app.TBarDropDown.ValueChangedFcn = createCallbackFcn(app, @TBarDropDownValueChanged, true);
            app.TBarDropDown.Value = 'A';
            app.SetasCOMButton = uibutton(app.AddEditArrayPanel, 'push');
            app.SetasCOMButton.ButtonPushedFcn = createCallbackFcn(app, @SetasCOMButtonPushed, true);
            app.SetasCOMButton.Text = 'Set as COM';
            app.SetasGNDButton = uibutton(app.AddEditArrayPanel, 'push');
            app.SetasGNDButton.ButtonPushedFcn = createCallbackFcn(app, @SetasGNDButtonPushed, true);
            app.SetasGNDButton.Text = 'Set as GND';
            app.EmptyChannelHandlingPanel = uipanel(app.LeftPanel);
            app.EmptyChannelHandlingPanel.Title = 'Empty Channel Handling';
            app.SRDropDownLabel = uilabel(app.EmptyChannelHandlingPanel);
            app.SRDropDownLabel.Enable = 'off';
            app.SRDropDownLabel.Text = 'SR';
            app.SRDropDown = uidropdown(app.EmptyChannelHandlingPanel);
            app.SRDropDown.Items = {'None', '500Hz', '1kHz', '2kHz', '10kHz', '30kHz', ''};
            app.SRDropDown.ValueChangedFcn = createCallbackFcn(app, @SRDropDownValueChanged, true);
            app.SRDropDown.Enable = 'off';
            app.SRDropDown.Value = '2kHz';
            app.SFDropDownLabel = uilabel(app.EmptyChannelHandlingPanel);
            app.SFDropDownLabel.Enable = 'off';
            app.SFDropDownLabel.Text = 'SF';
            app.SFDropDown = uidropdown(app.EmptyChannelHandlingPanel);
            app.SFDropDown.Items = {'None', '750Hz Highpass', '250Hz Highpass', '100Hz Highpass', '50Hz Lowpass', '125Hz Lowpass', '250Hz Lowpass', '500Hz Lowpass', '150Hz Lowpass', '10Hz-250Hz Bandpass', '2.5kHz Lowpass', '2kHz Lowpass', '250Hz-5kHz Bandpass'};
            app.SFDropDown.ValueChangedFcn = createCallbackFcn(app, @SFDropDownValueChanged, true);
            app.SFDropDown.Enable = 'off';
            app.SFDropDown.Value = '500Hz Lowpass';
            app.RecordEmptyChannelsCheckBox = uicheckbox(app.EmptyChannelHandlingPanel);
            app.RecordEmptyChannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecordEmptyChannelsCheckBoxValueChanged, true);
            app.RecordEmptyChannelsCheckBox.Text = 'Record Empty Channels';
            app.RecordEmptyChannelsCheckBox.Value = true;
            app.DropDown = uidropdown(app.EmptyChannelHandlingPanel);
            app.DropDown.Items = {'All', 'One', 'One per TBar', ''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'One per TBar';
            app.SwitchNSPsButton = uibutton(app.LeftPanel, 'push');
            app.SwitchNSPsButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchNSPsButtonPushed, true);
            app.SwitchNSPsButton.Text = 'Switch NSPs';
            app.ConfigureCablingButton = uibutton(app.LeftPanel, 'push');
            app.ConfigureCablingButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureCablingButtonPushed, true);
            app.ConfigureCablingButton.Text = 'Configure Cabling';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.NSP1Tab = uitab(app.TabGroup);
            app.NSP1Tab.Title = 'NSP1';
            app.NSP1Tab.ButtonDownFcn = createCallbackFcn(app, @SelectTab, true);
            app.NSP1_Table = uitable(app.NSP1Tab);
            app.NSP1_Table.ColumnName = {''};
            app.NSP1_Table.RowName = {};
            app.NSP1_Table.CellEditCallback = createCallbackFcn(app, @NSP1_TableCellEdit, true);
            app.NSP1_Table.CellSelectionCallback = createCallbackFcn(app, @NSP1_TableCellSelection, true);
            app.NSP2Tab = uitab(app.TabGroup);
            app.NSP2Tab.Title = 'NSP2';
            app.NSP2Tab.ButtonDownFcn = createCallbackFcn(app, @SelectTab, true);
            app.NSP2_Table = uitable(app.NSP2Tab);
            app.NSP2_Table.ColumnName = {''};
            app.NSP2_Table.RowName = {};
            app.NSP2_Table.CellEditCallback = createCallbackFcn(app, @NSP2_TableCellEdit, true);
            app.NSP2_Table.CellSelectionCallback = createCallbackFcn(app, @NSP2_TableCellSelection, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MontageSetup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end